classdef ISA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TextArea                  matlab.ui.control.TextArea
        DeltaTEditField           matlab.ui.control.EditField
        DeltaTEditFieldLabel      matlab.ui.control.Label
        DeltaTCheckBox            matlab.ui.control.CheckBox
        GOButton                  matlab.ui.control.Button
        AltitudeftEditField       matlab.ui.control.EditField
        AltitudeftEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            if app.DeltaTCheckBox.Value == 0
                app.TextArea.Value = convertCharsToStrings(quickISA(str2double(app.AltitudeftEditField.Value)));
            elseif app.DeltaTCheckBox.Value == 1
                app.TextArea.Value = convertCharsToStrings(deltatempISA(str2double(app.AltitudeftEditField.Value),str2double(app.DeltaTEditField.Value)));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AltitudeftEditFieldLabel = uilabel(app.UIFigure);
            app.AltitudeftEditFieldLabel.Text = 'Altitude (ft)';
            app.AltitudeftEditField = uieditfield(app.UIFigure, 'text');
            app.AltitudeftEditField.CharacterLimits = [0 100000];
            app.AltitudeftEditField.InputType = 'digits';
            app.GOButton = uibutton(app.UIFigure, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.DeltaTCheckBox = uicheckbox(app.UIFigure);
            app.DeltaTCheckBox.Text = 'Delta T?';
            app.DeltaTEditFieldLabel = uilabel(app.UIFigure);
            app.DeltaTEditFieldLabel.Text = 'Delta T';
            app.DeltaTEditField = uieditfield(app.UIFigure, 'text');
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ISA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end